classdef AstatickiProces1reda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BODEButton           matlab.ui.control.Button
        STEPButton           matlab.ui.control.Button
        K_RsLabel            matlab.ui.control.Label
        K_oRsLabel           matlab.ui.control.Label
        KRt                  matlab.ui.control.NumericEditField
        omegaC               matlab.ui.control.NumericEditField
        APROKSIMACIJAButton  matlab.ui.control.Button
        TIsField             matlab.ui.control.NumericEditField
        TIsLabel             matlab.ui.control.Label
        omegaIS              matlab.ui.control.NumericEditField
        IsLabel              matlab.ui.control.Label
        omegaCS              matlab.ui.control.NumericEditField
        csLabel              matlab.ui.control.Label
        Label_11             matlab.ui.control.Label
        Label_10             matlab.ui.control.Label
        Image5_4             matlab.ui.control.Image
        Image5_3             matlab.ui.control.Image
        Image5_2             matlab.ui.control.Image
        Image5               matlab.ui.control.Image
        Label_9              matlab.ui.control.Label
        OdabirparametararegulatoraLabel  matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        Image4               matlab.ui.control.Image
        LABEL1               matlab.ui.control.Label
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Tpv                  matlab.ui.control.NumericEditField
        sLabel_3             matlab.ui.control.Label
        Kpv                  matlab.ui.control.NumericEditField
        T2                   matlab.ui.control.NumericEditField
        sLabel_2             matlab.ui.control.Label
        K2                   matlab.ui.control.NumericEditField
        sLabel               matlab.ui.control.Label
        K1                   matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Image                matlab.ui.control.Image
        UnesitezadaneparametrelanovaregulacijskogkrugaLabel  matlab.ui.control.Label
        Label                matlab.ui.control.Label
        Button               matlab.ui.control.Button
        SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM1REDALabel  matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        K_R
        T_I
    end
    
    methods (Access = private)
        
        function crtajAsimptotskuAFK(app, omega_c, pocetni_nagib, nule, polovi)
            % Postavke
            w = logspace(log10(omega_c)-2, log10(omega_c)+2, 1000);
            mag = zeros(size(w));
            index_wc = find(w >= omega_c, 1);
            mag(index_wc) = 0;

            % Složimo sve lomne točke i pripremimo promjenu nagiba
            nule = nule(:).';   % pretvori u redak
            polovi = polovi(:).';  % isto
            lomne = [nule, polovi];
            promjene = [20*ones(1, numel(nule)), -20*ones(1, numel(polovi))];  % +20 za nule, -20 za polove

            % Sortiraj po frekvenciji
            [lomne_sort, idx_sort] = sort(lomne);
            promjene_sort = promjene(idx_sort);

            % Inicijalni nagib
            nagib = pocetni_nagib * 20;

            % Desno od ωc
            for i = index_wc+1:length(w)
                dw = log10(w(i)) - log10(w(i-1));
                mag(i) = mag(i-1) + nagib * dw;

                % Provjeri ima li lomna točka između w(i-1) i w(i)
                for j = 1:length(lomne_sort)
                    if w(i-1) < lomne_sort(j) && lomne_sort(j) <= w(i)
                        nagib = nagib + promjene_sort(j);
                    end
                end
            end

            % Lijevo od ωc
            nagib = pocetni_nagib * 20;
            for i = index_wc-1:-1:1
                dw = log10(w(i+1)) - log10(w(i));
                mag(i) = mag(i+1) - nagib * dw;

                for j = 1:length(lomne_sort)
                    if w(i) <= lomne_sort(j) && lomne_sort(j) < w(i+1)
                        nagib = nagib - promjene_sort(j);
                    end
                end
            end

            % Crtanje
            cla(app.UIAxes);
            semilogx(app.UIAxes, w, mag, 'r-', 'LineWidth', 2);
            hold(app.UIAxes, 'on');

            % Označi ωc
            y_wc = mag(index_wc);
            plot(app.UIAxes, omega_c, y_wc, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'k');
            text(app.UIAxes, omega_c, y_wc+5, '\omega_c', 'HorizontalAlignment', 'center');

            % Označi lomne frekvencije
            for w_b = lomne
                xline(app.UIAxes, w_b, '--k', 'Alpha', 0.4);
            end

            % Osa i naslovi
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Amplitudno-frekvencijska karakteristika');
            xlabel(app.UIAxes, 'Frekvencija [rad/s]');
            ylabel(app.UIAxes, 'Magnituda [dB]');
            % === Produžetak početnog pravca sa slajda 164 ===
            % Od točke pri w = 0.001
            w_ref = omega_c/100;
            idx = find(w >= w_ref, 1);
            A_ref = mag(idx);

            % Izračun nagiba lokalno (na 2 točke)
            slope = (mag(idx+1) - mag(idx)) / (log10(w(idx+1)) - log10(w(idx)));  % u dB/dec
            b = A_ref - slope * log10(w_ref);

            % Produži pravac po cijelom w
            mag_pocetni = slope * log10(w) + b;
            semilogx(app.UIAxes, w, mag_pocetni, 'b-.', 'LineWidth', 1.5);

            % Presjek s 0 dB osi
            if slope ~= 0
                w0 = 10^(-b / slope);
                plot(app.UIAxes, w0, 0, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'g');
                text(app.UIAxes, w0, 5, ['√K_{oR} ≈ ', num2str(w0, '%.2f')], ...
                    'Color', 'g', 'FontSize', 10, 'HorizontalAlignment', 'center');
            else
                % Ako je nagib 0, pravac nikad ne presiječe 0 dB
                text(app.UIAxes, w_ref, A_ref + 5, 'Ravan pravac (nagib = 0)', ...
                    'Color', 'b', 'FontSize', 10, 'HorizontalAlignment', 'left');
            end
            app.omegaC.Value = w0*w0;
            K_Rs = (w0*w0*app.TIsField.Value)/(app.K1.Value*app.K2.Value*app.Kpv.Value);
            app.KRt.Value = K_Rs;
            app.K_R = K_Rs;
            
        end

        function approxPhaseFromBreakPoints(app, nule, polovi, ax)
           
            % Prikazuje aproksimaciju faze iz poznatih lomnih točaka
            w = logspace(-3, 2, 1000);  % frekvencijski raspon
            phase = -180*ones(size(w));
            if any(abs(polovi) < 1e-6)
                phase = phase - 90;
            end

            % Doprinos nula
            for i = 1:length(nule)
                w0 = nule(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = 90;
                    else
                        dp = 45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end
            for i = 1:length(polovi)
                if polovi(i) == 0
                    phase = phase - 90;  % konstantan doprinos za sve frekvencije
                end
            end

            % Doprinos polova
            for i = 1:length(polovi)
                xline(ax, 10,'--');
                w0 = polovi(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = -90;
                    else
                        dp = -45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end

            % Crtanje
            semilogx(ax, w, phase, 'b-', 'LineWidth', 2);
            title(ax, 'Fazno-frekvecijska karakteristika');
            xlabel(ax, 'Frekvencija [rad/s]');
            ylabel(ax, 'Faza [°]');
            grid(ax, 'on');
            hold(ax, 'on');
            % prikaz polova
              
                omega_2 = 1/app.T2.Value; % Prvo w_2, pa 0.1w_2, pa 10w_2
                xline(ax, omega_2, '--', 'Color', [0.9290, 0.6940, 0.1250], ...
                'Alpha', 1, 'Label', sprintf('\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2/10, '--','Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2*10, '--', 'Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_pv = 1/app.Tpv.Value; % Prvo w_pv, pa 0.1w_pv, pa 10w_pv
                xline(ax, omega_pv, '--', 'Color', [0.3010, 0.7450, 0.9330], ...
                'Alpha', 1, 'Label', sprintf('\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv/10, '--','Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv*10, '--', 'Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_I = 1/app.T_I; % Prvo w_I, pa 0.1w_I, pa 10w_I
                xline(ax, omega_I, '--', 'Color', [0.4940, 0.1840, 0.5560], ...
                'Alpha', 1, 'Label', sprintf('\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_I/10, '--','Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_I*10, '--', 'Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
            hold(ax, 'off');

            % ispis vrijednosti u posbnom prozoru
            % Otvori novi uifigure prozor
            f = uifigure('Name', 'Polovi i nule sustava', 'Position', [100 10 350 200]);

            % Prikaži tekst u labeli
            uilabel(f, ...
                'Text', sprintf('Polovi i nule sustava su: [rad/s]'), ...
                'Position', [20 80 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_2 = %.2g, 0.1ω_2 = %.2g, 10ω_2 = %.2g', omega_2,0.1*omega_2,10*omega_2), ...
                'Position', [20 60 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_pv = %.2g, 0.1ω_pv = %.2g, 10ω_pv = %.2g', omega_pv,0.1*omega_pv,10*omega_pv), ...
                'Position', [20 40 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_I = %.2g, 0.1ω_I = %.2g, 10ω_I = %.2g', omega_I,0.1*omega_I,10*omega_I), ...
                'Position', [20 20 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            MainMenu();
            delete(app);
        end

        % Button pushed function: APROKSIMACIJAButton
        function APROKSIMACIJAButtonPushed(app, event)
            omega_2 = 1/app.T2.Value;
            omega_pv = 1/app.Tpv.Value;

            % Izračun omega_cs i omega_Is te T_Is
            if omega_2 > omega_pv
                omega_cs = omega_pv/3.2;
            else
                omega_cs = omega_2/3.2;
            end
            omega_Is = omega_cs/3.2;
            T_Is = 1/omega_Is;
            app.T_I = T_Is;
            app.omegaCS.Value = omega_cs;
            app.omegaIS.Value = omega_Is;
            app.TIsField.Value = T_Is;

            nule = [omega_Is];
            polovi = [omega_2; omega_pv];
            pocetni_nagib = -1;

            app.crtajAsimptotskuAFK(omega_cs, pocetni_nagib, nule, polovi);
            app.approxPhaseFromBreakPoints(nule, polovi, app.UIAxes_2);
        end

        % Button pushed function: STEPButton
        function STEPButtonPushed(app, event)
            % Napravi novi prozor (skočni)
            f = uifigure('Name', 'Odziv na STEP funkciju', 'Position', [100 100 500 400]);
    
            % Dodaj axes za odziv
            ax = uiaxes(f, 'Position', [50 50 400 300]);
            num1 = [app.K1.Value];
            den1 = [1 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            num_r = [app.T_I*app.K_R 1*app.K_R];
            den_r = [app.T_I 0];
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            sys = feedback(G_d,G_pv);
    
            % Nacrtaj odaziv na step funkciju
            [t, y] = step(sys);
            plot(ax, y, t, 'b-', 'LineWidth', 1);
            xlabel(ax, 'Vrijeme [s]');
            ylabel(ax, 'Vrijednost odziva');
            title(ax, 'Odaziv sustava na step funkciju');
            grid(ax, 'on');
        end

        % Button pushed function: BODEButton
        function BODEButtonPushed(app, event)

            num1 = [app.K1.Value];
            den1 = [1 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            num_r = [app.T_I*app.K_R 1*app.K_R];
            den_r = [app.T_I 0];
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            
            G_oR = G_d*G_pv;
            margin(G_oR);
            title('Bodeov dijagram');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM1REDALabel = uilabel(app.UIFigure);
            app.SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM1REDALabel.Text = 'SINTEZA REGULACIJSKOG SUSTAVA SA ASTATIČKIM PROCESOM 1. REDA';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Za pravilno korištenje programa pratite upute '; 'koje se nalaze u glavnom izborniku'; ''};
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel = uilabel(app.UIFigure);
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel.Text = '1. Unesite zadane parametre članova regulacijskog kruga:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '1.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '____________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '____________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '____________';
            app.K1 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.K2 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '1 +            s';
            app.T2 = uieditfield(app.UIFigure, 'numeric');
            app.Kpv = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_3 = uilabel(app.UIFigure);
            app.sLabel_3.Text = '1 +            s';
            app.Tpv = uieditfield(app.UIFigure, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '3.png');
            app.LABEL1 = uilabel(app.UIFigure);
            app.LABEL1.Text = {'Budući da se astatizam (r = 1) nalazi u procesu, statička pogreška'; 'u odnosu  na upravljačku veličinu δ_us bit će jednaka nuli čak i uz '; 'izbor čiste  proporcionalne strukture regulatora. '; 'Međutim, da bi statička pogreška u odnosu na poremećajnu veličinu '; 'δ_fs bila jednaka nuli, astatizam se mora nalaziti u regulatoru!'; 'Stoga se izabire PI regulator: I dio zbog gornjeg zahtjeva za '; 'astatizmom u regulatoru, a P dio, kao i u slučaju statičkog procesa, '; 'radi ubrzanja odziva, smanjenja kinetičke pogreške i bolje '; 'kompenzacije poremećaja.'; ''};
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '4.png');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Uz postignut red astatizma r = 2 zadovoljeni su zahtjevi za potpunom'; 'kompenzacijom poremećajne veličine (δ_fs = 0) te za statičkom pogreškom'; 'u odnosu na upravljačku (referentnu) veličinu jednakoj nuli (δ_us = 0).'};
            app.OdabirparametararegulatoraLabel = uilabel(app.UIFigure);
            app.OdabirparametararegulatoraLabel.Text = '2. Odabir parametara regulatora:';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'U prijenosnoj funkciji otvorenog kruga s regulatorom GoR(s) nepoznati'; 'su parametri K_oR i T_I.'; 'Prvo se odabire integracijska vremenska konstanta regulatora T_I.'; 'Budući da fazno-frekvencijska karakteristika već na niskim'; 'frekvencijama iznosi -180°, radi stabilizacije regulacijskog sustava'; 'potrebno je podići fazno-frekvencijsku karakteristiku iznad -180°.'; 'To se postiže tako da vrijedi: T_I > max(T_2, T_pv).'; 'Točan iznos TI može se odrediti primjenom simetričnog optimuma kao'; 'kod statičkog procesa, a preporuka je odabrati:'};
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '5.png');
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '6.png');
            app.Image5_3 = uiimage(app.UIFigure);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '7.png');
            app.Image5_4 = uiimage(app.UIFigure);
            app.Image5_4.ImageSource = fullfile(pathToMLAPP, 'astaticki1red', '8.png');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = {'U prijenosnoj funkciji G_oRs(s) nepoznat je samo koeficijent pojačanja K_oRs.'; 'Postupak određivanja pojačanja K_oRs sličan je kao i za'; 'statički proces:'; '• kreće se s crtanjem amplitudno frekvencijske karakteristikeoko'; '  presječne frekvencije'; '• K_oRs se očitava na dijelu karakteristike do lomne '; '  frekvencije ω_Is (niske frekvencije) produljenjem tog dijela '; '  karakteristike do osi 0 dB i očitavanjem pripadne '; '  frekvencije koja je jednaka korijenu iz K_oRs.'; 'Koeficijent pojačanja regulatora K_Rs računa se prema:'};
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = {'Nakon toga se dodatno može povećati koeficijent pojačanja '; 'regulatora  K_Rs radi bolje kompenzacije poremećaja i nakon '; 'toga obaviti zahvate u grani referentne veličine, kao i u '; 'dosadašnjim primjerima sa statičkim procesima.'};
            app.csLabel = uilabel(app.UIFigure);
            app.csLabel.Text = 'ωcs  =';
            app.omegaCS = uieditfield(app.UIFigure, 'numeric');
            app.IsLabel = uilabel(app.UIFigure);
            app.IsLabel.Text = 'ωIs  =';
            app.omegaIS = uieditfield(app.UIFigure, 'numeric');
            app.TIsLabel = uilabel(app.UIFigure);
            app.TIsLabel.Text = 'TIs  =';
            app.TIsField = uieditfield(app.UIFigure, 'numeric');
            app.APROKSIMACIJAButton = uibutton(app.UIFigure, 'push');
            app.APROKSIMACIJAButton.ButtonPushedFcn = createCallbackFcn(app, @APROKSIMACIJAButtonPushed, true);
            app.APROKSIMACIJAButton.Text = 'APROKSIMACIJA';
            app.omegaC = uieditfield(app.UIFigure, 'numeric');
            app.KRt = uieditfield(app.UIFigure, 'numeric');
            app.K_oRsLabel = uilabel(app.UIFigure);
            app.K_oRsLabel.Text = 'K_oRs =';
            app.K_RsLabel = uilabel(app.UIFigure);
            app.K_RsLabel.Text = 'K_Rs =';
            app.STEPButton = uibutton(app.UIFigure, 'push');
            app.STEPButton.ButtonPushedFcn = createCallbackFcn(app, @STEPButtonPushed, true);
            app.STEPButton.Text = 'STEP';
            app.BODEButton = uibutton(app.UIFigure, 'push');
            app.BODEButton.ButtonPushedFcn = createCallbackFcn(app, @BODEButtonPushed, true);
            app.BODEButton.Text = 'BODE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AstatickiProces1reda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end