classdef AstatickiProces2reda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BODEButton_2             matlab.ui.control.Button
        STEPButton_2             matlab.ui.control.Button
        BODEButton               matlab.ui.control.Button
        STEPButton               matlab.ui.control.Button
        Lamp_2                   matlab.ui.control.Lamp
        LabelDT1_2               matlab.ui.control.Label
        T1_2                     matlab.ui.control.NumericEditField
        LabelDT2_2               matlab.ui.control.Label
        TD_2                     matlab.ui.control.NumericEditField
        LabelDT3_2               matlab.ui.control.Label
        LabelDT4_2               matlab.ui.control.Label
        cLabel                   matlab.ui.control.Label
        K_RLabel                 matlab.ui.control.Label
        K_oR_c2Label             matlab.ui.control.Label
        racunajK                 matlab.ui.control.Button
        KR                       matlab.ui.control.NumericEditField
        K_oRt_cLabel             matlab.ui.control.Label
        KoR                      matlab.ui.control.NumericEditField
        Label_11                 matlab.ui.control.Label
        omega_c                  matlab.ui.control.NumericEditField
        prijelomaEditFieldLabel  matlab.ui.control.Label
        OitajLabel               matlab.ui.control.Label
        APROKSIMACIJA_PDT1       matlab.ui.control.Button
        ImagePDT2                matlab.ui.control.Image
        Lamp                     matlab.ui.control.Lamp
        PROVJERAUNOSAPDTButton   matlab.ui.control.Button
        PROVJERAUNOSADTButton    matlab.ui.control.Button
        APROKSIMACIJAButton      matlab.ui.control.Button
        LabelDT1                 matlab.ui.control.Label
        T1                       matlab.ui.control.NumericEditField
        LabelDT2                 matlab.ui.control.Label
        TD                       matlab.ui.control.NumericEditField
        LabelPDT3                matlab.ui.control.Label
        LabelDT3                 matlab.ui.control.Label
        LabelDT4                 matlab.ui.control.Label
        ImageDT2                 matlab.ui.control.Image
        PDT1                     matlab.ui.control.CheckBox
        DT1                      matlab.ui.control.CheckBox
        OdabirparametararegulatoraLabel  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        LABEL1                   matlab.ui.control.Label
        Image2                   matlab.ui.control.Image
        Tpv                      matlab.ui.control.NumericEditField
        sLabel_3                 matlab.ui.control.Label
        Kpv                      matlab.ui.control.NumericEditField
        T2                       matlab.ui.control.NumericEditField
        sLabel_2                 matlab.ui.control.Label
        K2                       matlab.ui.control.NumericEditField
        sLabel                   matlab.ui.control.Label
        K1                       matlab.ui.control.NumericEditField
        Label_7                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        UnesitezadaneparametrelanovaregulacijskogkrugaLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM2REDALabel  matlab.ui.control.Label
        Image3                   matlab.ui.control.Image
        ImagePDT1                matlab.ui.control.Image
        ImageDT1                 matlab.ui.control.Image
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        K_R
        K_oR
    end
    
    methods (Access = private)
        
        function DT1_regulator(app, stanje)
            if stanje == 1
                vidljivo = 'on';
            else
                vidljivo = 'off';
            end

            app.LabelDT1_2.Visible = vidljivo;
            app.LabelDT2_2.Visible = vidljivo;
            app.LabelDT3_2.Visible = vidljivo;
            app.LabelDT4_2.Visible = vidljivo;
            app.ImageDT1.Visible = vidljivo;
            app.ImageDT2.Visible = vidljivo;
            app.T1_2.Visible = vidljivo;
            app.TD_2.Visible = vidljivo;
            app.APROKSIMACIJAButton.Visible = vidljivo;
            app.PROVJERAUNOSADTButton.Visible = vidljivo;
            app.Lamp_2.Visible = vidljivo;
            app.K_RLabel.Visible = vidljivo;
            app.KR.Visible = vidljivo;
            app.K_R = 1;
            app.STEPButton.Visible = vidljivo;
            app.BODEButton.Visible = vidljivo;
        end

        function PDT1_regulator(app, stanje)
            if stanje == 1
                vidljivo = 'on';
            else
                vidljivo = 'off';
            end

            app.LabelDT2.Visible = vidljivo;
            app.LabelDT3.Visible = vidljivo;
            app.LabelDT4.Visible = vidljivo;
            app.LabelPDT3.Visible = vidljivo;
            app.ImagePDT1.Visible = vidljivo;
            app.ImagePDT2.Visible = vidljivo;
            app.T1.Visible = vidljivo;
            app.TD.Visible = vidljivo;
            app.APROKSIMACIJA_PDT1.Visible = vidljivo;
            app.PROVJERAUNOSAPDTButton.Visible = vidljivo;
            app.Lamp.Visible = vidljivo;
            app.OitajLabel.Visible = vidljivo;
            app.cLabel.Visible = vidljivo;
            app.omega_c.Visible = vidljivo;
            app.racunajK.Visible = vidljivo;
            app.K_oR_c2Label.Visible = vidljivo;
            app.KoR.Visible = vidljivo;
            app.K_RLabel.Visible = vidljivo;
            app.KR.Visible = vidljivo;
            app.STEPButton_2.Visible = vidljivo;
            app.BODEButton_2.Visible = vidljivo;
        
        end

        function crtajAsimptotskuAFK(app, omega_c, pocetni_nagib, nule, polovi)
    % Postavke
            w = logspace(log10(omega_c)-2, log10(omega_c)+2, 1000);
            mag = zeros(size(w));
            index_wc = find(w >= omega_c, 1);
            mag(index_wc) = 0;

            % Složimo sve lomne točke i pripremimo promjenu nagiba
            nule = nule(:).';   % pretvori u redak
            polovi = polovi(:).';  % isto
            lomne = [nule, polovi];
            promjene = [20*ones(1, numel(nule)), -20*ones(1, numel(polovi))];  % +20 za nule, -20 za polove

            % Sortiraj po frekvenciji
            [lomne_sort, idx_sort] = sort(lomne);
            promjene_sort = promjene(idx_sort);

            % Inicijalni nagib
            nagib = pocetni_nagib * 20;

            % Desno od ωc
            for i = index_wc+1:length(w)
                dw = log10(w(i)) - log10(w(i-1));
                mag(i) = mag(i-1) + nagib * dw;

                % Provjeri ima li lomna točka između w(i-1) i w(i)
                for j = 1:length(lomne_sort)
                    if w(i-1) < lomne_sort(j) && lomne_sort(j) <= w(i)
                        nagib = nagib + promjene_sort(j);
                    end
                end
            end

            % Lijevo od ωc
            nagib = pocetni_nagib * 20;
            for i = index_wc-1:-1:1
                dw = log10(w(i+1)) - log10(w(i));
                mag(i) = mag(i+1) - nagib * dw;

                for j = 1:length(lomne_sort)
                    if w(i) <= lomne_sort(j) && lomne_sort(j) < w(i+1)
                        nagib = nagib - promjene_sort(j);
                    end
                end
            end

            % Crtanje
            cla(app.UIAxes);
            semilogx(app.UIAxes, w, mag, 'r-', 'LineWidth', 2);
            hold(app.UIAxes, 'on');

            % Označi lomne frekvencije
            for w_b = lomne
                xline(app.UIAxes, w_b, '--k', 'Alpha', 0.4);
            end

            % Osa i naslovi
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Amplitudno-frekvencijska karakteristika');
            xlabel(app.UIAxes, 'Frekvencija [rad/s]');
            ylabel(app.UIAxes, 'Magnituda [dB]');

            
        end


        function approxPhaseFromBreakPoints(app, nule, polovi, ax)
           
            % Prikazuje aproksimaciju faze iz poznatih lomnih točaka
            w = logspace(-4, 2, 1000);  % frekvencijski raspon
            phase = -180*ones(size(w));
            if any(abs(polovi) < 1e-6)
                phase = phase - 90;
            end

            % Doprinos nula
            for i = 1:length(nule)
                w0 = nule(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = 90;
                    else
                        dp = 45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end
            for i = 1:length(polovi)
                if polovi(i) == 0
                    phase = phase - 90;  % konstantan doprinos za sve frekvencije
                end
            end

            % Doprinos polova
            for i = 1:length(polovi)
                xline(ax, 10,'--');
                w0 = polovi(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = -90;
                    else
                        dp = -45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end

            % Crtanje
            semilogx(ax, w, phase, 'b-', 'LineWidth', 2);
            title(ax, 'Fazno-frekvencijska karakteristika');
            xlabel(ax, 'Frekvencija [rad/s]');
            ylabel(ax, 'Faza [°]');
            grid(ax, 'on');
            hold(ax, 'on');
            % prikaz polova
            
            if app.TD.Value > 0
                omega_D = app.TD.Value;
                omega_1 = app.T1.Value;
            else
                omega_D = app.TD_2.Value;
                omega_1 = app.T1_2.Value;
            end
                % Prvo w_1, pa 0.1w_1, pa 10w_1
                xline(ax, omega_1, '--', 'Color', [0.9290, 0.6940, 0.1250], ...
                'Alpha', 1, 'Label', sprintf('\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_1/10, '--','Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_1*10, '--', 'Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                
                omega_2 = 1/app.T2.Value; % Prvo w_2, pa 0.1w_2, pa 10w_2
                xline(ax, omega_2, '--', 'Color', [0.9290, 0.6940, 0.1250], ...
                'Alpha', 1, 'Label', sprintf('\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2/10, '--','Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2*10, '--', 'Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_pv = 1/app.Tpv.Value; % Prvo w_pv, pa 0.1w_pv, pa 10w_pv
                xline(ax, omega_pv, '--', 'Color', [0.3010, 0.7450, 0.9330], ...
                'Alpha', 1, 'Label', sprintf('\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv/10, '--','Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv*10, '--', 'Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                % Prvo w_D, pa 0.1w_D, pa 10w_D
                xline(ax, omega_D, '--', 'Color', [0.4940, 0.1840, 0.5560], ...
                'Alpha', 1, 'Label', sprintf('\\omega_D'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_D/10, '--','Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_D'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_D*10, '--', 'Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_D'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
            hold(ax, 'off');

            % ispis vrijednosti u posbnom prozoru
            % Otvori novi uifigure prozor
            f = uifigure('Name', 'Polovi i nule sustava', 'Position', [100 10 350 200]);

            % Prikaži tekst u labeli
            uilabel(f, ...
                'Text', sprintf('Polovi i nule sustava su: [rad/s]'), ...
                'Position', [20 80 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_1 = %.2g, 0.1ω_1 = %.2g, 10ω_1 = %.2g', omega_1,0.1*omega_1,10*omega_1), ...
                'Position', [20 60 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_2 = %.2g, 0.1ω_2 = %.2g, 10ω_2 = %.2g', omega_2,0.1*omega_2,10*omega_2), ...
                'Position', [20 40 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_pv = %.2g, 0.1ω_pv = %.2g, 10ω_pv = %.2g', omega_pv,0.1*omega_pv,10*omega_pv), ...
                'Position', [20 20 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_D = %.2g, 0.1ω_D = %.2g, 10ω_D = %.2g', omega_D,0.1*omega_D,10*omega_D), ...
                'Position', [20 0 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            MainMenu();
            delete(app);
        end

        % Value changed function: DT1
        function DT1ValueChanged(app, event)
            if app.DT1.Value
                app.PDT1.Enable = 'off'; % onemogući drugi
                DT1_regulator(app, 1);
                PDT1_regulator(app, 0);
            else
                app.PDT1.Enable = 'on'; % ponovno omogući
                DT1_regulator(app, 0);
                PDT1_regulator(app, 0);
            end
            
        end

        % Value changed function: PDT1
        function PDT1ValueChanged(app, event)
            if app.PDT1.Value
                app.DT1.Enable = 'off'; % onemogući drugi
                DT1_regulator(app, 0);
                PDT1_regulator(app, 1);
            else
                app.DT1.Enable = 'on'; % ponovno omogući
                DT1_regulator(app, 0);
                PDT1_regulator(app, 0);
            end
            
        end

        % Button pushed function: PROVJERAUNOSADTButton
        function PROVJERAUNOSADTButtonPushed(app, event)
            TD_vrijednost = app.TD_2.Value;
            T1_vrijednost = app.T1_2.Value;
            omjer = TD_vrijednost/T1_vrijednost;

            if omjer >=2 && omjer <= 10
                app.Lamp_2.Color = [0 1 0]; % Zeleno
                app.Lamp_2.Tooltip = 'Unos je ispravan';
            else
                app.Lamp_2.Color = [1 0 0]; % Crveno
                app.Lamp_2.Tooltip = 'Neispravan unos (Omjer mora biti između 2 i 10)';
            end
        end

        % Button pushed function: APROKSIMACIJAButton
        function APROKSIMACIJAButtonPushed(app, event)
            w_2 = 1/app.T2.Value;
            w_pv = 1/app.Tpv.Value;
            w_1 = 1/app.T1_2.Value;
            w_d = 1/app.TD_2.Value;
            K_1 = app.K1.Value;
            K_2 = app.K2.Value;
            K_pv = app.Kpv.Value;
            app.K_oR = app.TD_2.Value*K_1*K_2*K_pv;
            
            nule = [w_d];
            polovi = [w_2; w_pv; w_1];
            
            w_c = sqrt(app.K_oR);
            app.crtajAsimptotskuAFK(w_c, -1, nule, polovi);
            app.approxPhaseFromBreakPoints(nule, polovi, app.UIAxes_2);
        end

        % Button pushed function: PROVJERAUNOSAPDTButton
        function PROVJERAUNOSAPDTButtonPushed(app, event)
            TD_vrijednost = app.TD.Value;
            T1_vrijednost = app.T1.Value;
            omjer = TD_vrijednost/T1_vrijednost;

            if omjer >=4 && omjer <= 10
                app.Lamp.Color = [0 1 0]; % Zeleno
                app.Lamp.Tooltip = 'Unos je ispravan';
            else
                app.Lamp.Color = [1 0 0]; % Crveno
                app.Lamp.Tooltip = 'Neispravan unos (Omjer mora biti između 4 i 10)';
            end
        end

        % Button pushed function: APROKSIMACIJA_PDT1
        function APROKSIMACIJA_PDT1ButtonPushed(app, event)
            w_2 = 1/app.T2.Value;
            w_pv = 1/app.Tpv.Value;
            w_1 = 1/app.T1.Value;
            w_d = 1/app.TD.Value;
            
            nule = [w_d];
            polovi = [w_2; w_pv; w_1];

            app.approxPhaseFromBreakPoints(nule, polovi, app.UIAxes_2);
        end

        % Button pushed function: racunajK
        function racunajKButtonPushed(app, event)
            w_2 = 1/app.T2.Value;
            w_pv = 1/app.Tpv.Value;
            w_1 = 1/app.T1.Value;
            w_d = 1/app.TD.Value;
            K_1 = app.K1.Value;
            K_2 = app.K2.Value;
            K_pv = app.Kpv.Value;
            w_c = app.omega_c.Value;
            app.K_oR = w_c*w_c;
            app.KoR.Value = app.K_oR;
            app.K_R = app.K_oR/(K_1*K_2*K_pv);
            app.KR.Value = app.K_R;
            nule = [w_d];
            polovi = [w_2; w_pv; w_1];
            app.crtajAsimptotskuAFK(w_c, -1, nule, polovi);
        end

        % Button pushed function: STEPButton
        function STEPButtonPushed(app, event)
            % Napravi novi prozor (skočni)
            f = uifigure('Name', 'Odziv na STEP funkciju', 'Position', [100 100 500 400]);
    
            % Dodaj axes za odziv
            ax = uiaxes(f, 'Position', [50 50 400 300]);
    
            num1 = [app.K1.Value];
            den1 = [1 0 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            if app.TD.Value > 0
                num_r = [app.TD.Value 0];
                den_r = [app.T1.Value 1];
            else
                num_r = [app.TD_2.Value 0];
                den_r = [app.T1_2.Value 1];
            end
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            sys = feedback(G_d,G_pv);
    
            % Nacrtaj odaziv na step funkciju
            [t, y] = step(sys);
            plot(ax, y, t, 'b-', 'LineWidth', 1);
            xlabel(ax, 'Vrijeme [s]');
            ylabel(ax, 'Vrijednost odziva');
            title(ax, 'Odaziv sustava na step funkciju');
            grid(ax, 'on');
        end

        % Button pushed function: BODEButton
        function BODEButtonPushed(app, event)

            num1 = [app.K1.Value];
            den1 = [1 0 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            if app.TD.Value > 0
                num_r = [app.TD.Value 0];
                den_r = [app.T1.Value 1];
            else
                num_r = [app.TD_2.Value 0];
                den_r = [app.T1_2.Value 1];
            end
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            
            G_oR = G_d*G_pv;
            margin(G_oR);
            title('Bodeov dijagram');
        end

        % Button pushed function: STEPButton_2
        function STEPButton_2Pushed(app, event)
            % Napravi novi prozor (skočni)
            f = uifigure('Name', 'Odziv na STEP funkciju', 'Position', [100 100 500 400]);
    
            % Dodaj axes za odziv
            ax = uiaxes(f, 'Position', [50 50 400 300]);
    
            num1 = [app.K1.Value];
            den1 = [1 0 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            if app.TD.Value > 0
                num_r = [app.TD.Value 1];
                den_r = [app.T1.Value 1];
            else
                num_r = [app.TD_2.Value 1];
                den_r = [app.T1_2.Value 1];
            end
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            sys = feedback(G_d,G_pv);
    
            % Nacrtaj odaziv na step funkciju
            [t, y] = step(sys);
            plot(ax, y, t, 'b-', 'LineWidth', 1);
            xlabel(ax, 'Vrijeme [s]');
            ylabel(ax, 'Vrijednost odziva');
            title(ax, 'Odaziv sustava na step funkciju');
            grid(ax, 'on');
        end

        % Button pushed function: BODEButton_2
        function BODEButton_2Pushed(app, event)
            num1 = [app.K1.Value];
            den1 = [1 0 0];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            if app.TD.Value > 0
                num_r = [app.TD.Value 1];
                den_r = [app.T1.Value 1];
            else
                num_r = [app.TD_2.Value 1];
                den_r = [app.T1_2.Value 1];
            end
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            
            G_oR = G_d*G_pv;
            margin(G_oR);
            title('Bodeov dijagram');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ImageDT1 = uiimage(app.UIFigure);
            app.ImageDT1.Visible = 'off';
            app.ImageDT1.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '4.png');
            app.ImagePDT1 = uiimage(app.UIFigure);
            app.ImagePDT1.Visible = 'off';
            app.ImagePDT1.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '6.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '3.png');
            app.SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM2REDALabel = uilabel(app.UIFigure);
            app.SINTEZAREGULACIJSKOGSUSTAVASAASTATIKIMPROCESOM2REDALabel.Text = 'SINTEZA REGULACIJSKOG SUSTAVA SA ASTATIČKIM PROCESOM 2. REDA';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Za pravilno korištenje programa pratite upute '; 'koje se nalaze u glavnom izborniku'; ''};
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel = uilabel(app.UIFigure);
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel.Text = '1. Unesite zadane parametre članova regulacijskog kruga:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '1.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '____________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '____________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '____________';
            app.K1 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.K2 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '1 +            s';
            app.T2 = uieditfield(app.UIFigure, 'numeric');
            app.Kpv = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_3 = uilabel(app.UIFigure);
            app.sLabel_3.Text = '1 +            s';
            app.Tpv = uieditfield(app.UIFigure, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '2.png');
            app.LABEL1 = uilabel(app.UIFigure);
            app.LABEL1.Text = {'Budući da se astatizam (r = 2) nalazi u procesu, statička pogreška'; 'u odnosu  na upravljačku veličinu δ_us bit će jednaka nuli čak i uz '; 'izbor čiste  proporcionalne strukture regulatora. '; 'Statička pogreška u odnosu na poremećajnu veličinu δ_fs neće biti'; 'jednaka nuli, jer bi se astatizam morao nalaziti u regulatoru.'; 'Međutim, s čistim P regulatorom, kao i s dodavanjem astatizma u '; 'regulator (I dio) onemogućili bi stabilizaciju sustava.'; 'Da bi podigli fazno-frekvencijsku karakteristiku iznad -180° regulator '; 'mora sadržavati derivacijsko djelovanje - DT1 ili PDT1 regulator s '; 'faznim prethođenjem:'; ''};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '2';
            app.OdabirparametararegulatoraLabel = uilabel(app.UIFigure);
            app.OdabirparametararegulatoraLabel.Text = '2. Odabir parametara regulatora:';
            app.DT1 = uicheckbox(app.UIFigure);
            app.DT1.ValueChangedFcn = createCallbackFcn(app, @DT1ValueChanged, true);
            app.DT1.Text = 'DT1 regulator';
            app.PDT1 = uicheckbox(app.UIFigure);
            app.PDT1.ValueChangedFcn = createCallbackFcn(app, @PDT1ValueChanged, true);
            app.PDT1.Text = 'PDT1 regulator';
            app.ImageDT2 = uiimage(app.UIFigure);
            app.ImageDT2.Visible = 'off';
            app.ImageDT2.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '5.png');
            app.LabelDT4 = uilabel(app.UIFigure);
            app.LabelDT4.Visible = 'off';
            app.LabelDT4.Text = '=';
            app.LabelDT3 = uilabel(app.UIFigure);
            app.LabelDT3.Visible = 'off';
            app.LabelDT3.Text = '____________';
            app.LabelPDT3 = uilabel(app.UIFigure);
            app.LabelPDT3.Visible = 'off';
            app.LabelPDT3.Text = '1 +             s';
            app.TD = uieditfield(app.UIFigure, 'numeric');
            app.TD.Visible = 'off';
            app.LabelDT2 = uilabel(app.UIFigure);
            app.LabelDT2.Visible = 'off';
            app.LabelDT2.Text = '1 +            s';
            app.T1 = uieditfield(app.UIFigure, 'numeric');
            app.T1.Visible = 'off';
            app.LabelDT1 = uilabel(app.UIFigure);
            app.LabelDT1.Visible = 'off';
            app.LabelDT1.Text = ' s';
            app.APROKSIMACIJAButton = uibutton(app.UIFigure, 'push');
            app.APROKSIMACIJAButton.ButtonPushedFcn = createCallbackFcn(app, @APROKSIMACIJAButtonPushed, true);
            app.APROKSIMACIJAButton.Visible = 'off';
            app.APROKSIMACIJAButton.Text = 'APROKSIMACIJA';
            app.PROVJERAUNOSADTButton = uibutton(app.UIFigure, 'push');
            app.PROVJERAUNOSADTButton.ButtonPushedFcn = createCallbackFcn(app, @PROVJERAUNOSADTButtonPushed, true);
            app.PROVJERAUNOSADTButton.Visible = 'off';
            app.PROVJERAUNOSADTButton.Text = 'PROVJERA UNOSA';
            app.PROVJERAUNOSAPDTButton = uibutton(app.UIFigure, 'push');
            app.PROVJERAUNOSAPDTButton.ButtonPushedFcn = createCallbackFcn(app, @PROVJERAUNOSAPDTButtonPushed, true);
            app.PROVJERAUNOSAPDTButton.Visible = 'off';
            app.PROVJERAUNOSAPDTButton.Text = 'PROVJERA UNOSA';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.ImagePDT2 = uiimage(app.UIFigure);
            app.ImagePDT2.Visible = 'off';
            app.ImagePDT2.ImageSource = fullfile(pathToMLAPP, 'astaticki2red', '7.png');
            app.APROKSIMACIJA_PDT1 = uibutton(app.UIFigure, 'push');
            app.APROKSIMACIJA_PDT1.ButtonPushedFcn = createCallbackFcn(app, @APROKSIMACIJA_PDT1ButtonPushed, true);
            app.APROKSIMACIJA_PDT1.Visible = 'off';
            app.APROKSIMACIJA_PDT1.Text = 'APROKSIMACIJA';
            app.OitajLabel = uilabel(app.UIFigure);
            app.OitajLabel.Visible = 'off';
            app.OitajLabel.Text = {'Očitaj i upiši frekvenciju otprilke na sredini "zvona"'; 'nakon prve promjene na FFK dijagramu.'};
            app.prijelomaEditFieldLabel = uilabel(app.UIFigure);
            app.prijelomaEditFieldLabel.Text = '';
            app.omega_c = uieditfield(app.UIFigure, 'numeric');
            app.omega_c.Visible = 'off';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.KoR = uieditfield(app.UIFigure, 'numeric');
            app.KoR.Visible = 'off';
            app.K_oRt_cLabel = uilabel(app.UIFigure);
            app.K_oRt_cLabel.Text = '';
            app.KR = uieditfield(app.UIFigure, 'numeric');
            app.KR.Visible = 'off';
            app.racunajK = uibutton(app.UIFigure, 'push');
            app.racunajK.ButtonPushedFcn = createCallbackFcn(app, @racunajKButtonPushed, true);
            app.racunajK.Visible = 'off';
            app.racunajK.Text = 'Izračunaj pojačanje';
            app.K_oR_c2Label = uilabel(app.UIFigure);
            app.K_oR_c2Label.Visible = 'off';
            app.K_oR_c2Label.Text = 'K_oR = ω_c^2 =';
            app.K_RLabel = uilabel(app.UIFigure);
            app.K_RLabel.Visible = 'off';
            app.K_RLabel.Text = 'K_R =';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Visible = 'off';
            app.cLabel.Text = 'ω_c =';
            app.LabelDT4_2 = uilabel(app.UIFigure);
            app.LabelDT4_2.Visible = 'off';
            app.LabelDT4_2.Text = '=';
            app.LabelDT3_2 = uilabel(app.UIFigure);
            app.LabelDT3_2.Visible = 'off';
            app.LabelDT3_2.Text = '____________';
            app.TD_2 = uieditfield(app.UIFigure, 'numeric');
            app.TD_2.Visible = 'off';
            app.LabelDT2_2 = uilabel(app.UIFigure);
            app.LabelDT2_2.Visible = 'off';
            app.LabelDT2_2.Text = '1 +            s';
            app.T1_2 = uieditfield(app.UIFigure, 'numeric');
            app.T1_2.Visible = 'off';
            app.LabelDT1_2 = uilabel(app.UIFigure);
            app.LabelDT1_2.Visible = 'off';
            app.LabelDT1_2.Text = ' s';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Visible = 'off';
            app.STEPButton = uibutton(app.UIFigure, 'push');
            app.STEPButton.ButtonPushedFcn = createCallbackFcn(app, @STEPButtonPushed, true);
            app.STEPButton.Text = 'STEP';
            app.BODEButton = uibutton(app.UIFigure, 'push');
            app.BODEButton.ButtonPushedFcn = createCallbackFcn(app, @BODEButtonPushed, true);
            app.BODEButton.Text = 'BODE';
            app.STEPButton_2 = uibutton(app.UIFigure, 'push');
            app.STEPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STEPButton_2Pushed, true);
            app.STEPButton_2.Text = 'STEP';
            app.BODEButton_2 = uibutton(app.UIFigure, 'push');
            app.BODEButton_2.ButtonPushedFcn = createCallbackFcn(app, @BODEButton_2Pushed, true);
            app.BODEButton_2.Text = 'BODE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AstatickiProces2reda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end