classdef StatickiProces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        KRt_2                matlab.ui.control.NumericEditField
        omegaC_2             matlab.ui.control.NumericEditField
        BODEButton           matlab.ui.control.Button
        STEPButton           matlab.ui.control.Button
        Opcija2Label6        matlab.ui.control.Label
        Opcija2Label5        matlab.ui.control.Label
        RacunajButton        matlab.ui.control.Button
        TIsField             matlab.ui.control.NumericEditField
        Opcija2Label4        matlab.ui.control.Label
        omegaIS              matlab.ui.control.NumericEditField
        Opcija2Label3        matlab.ui.control.Label
        omegaCS              matlab.ui.control.NumericEditField
        Opcija2Label2        matlab.ui.control.Label
        Opcija2Image2        matlab.ui.control.Image
        Opcija2Image1        matlab.ui.control.Image
        Opcija1Label14       matlab.ui.control.Label
        Opcija1Label13       matlab.ui.control.Label
        Opcija1Label12       matlab.ui.control.Label
        Opcija1Label11       matlab.ui.control.Label
        Opcija1Label8        matlab.ui.control.Label
        Opcija1Label10       matlab.ui.control.Label
        racunajK             matlab.ui.control.Button
        KRt                  matlab.ui.control.NumericEditField
        omegaC               matlab.ui.control.NumericEditField
        omegaPrijeloma       matlab.ui.control.NumericEditField
        phi                  matlab.ui.control.NumericEditField
        nagibPravca          matlab.ui.control.NumericEditField
        Opcija1Label9        matlab.ui.control.Label
        phioRt               matlab.ui.control.NumericEditField
        Opcija1Label4        matlab.ui.control.Label
        Opcija1Label5        matlab.ui.control.Label
        Opcija1Label7        matlab.ui.control.Label
        Opcija1Image3        matlab.ui.control.Image
        Opcija1Label6        matlab.ui.control.Label
        Opcija1Image2        matlab.ui.control.Image
        Opcija1Image4        matlab.ui.control.Image
        APROKSIMACIJAButton  matlab.ui.control.Button
        sigmaM               matlab.ui.control.NumericEditField
        Opcija1Label3        matlab.ui.control.Label
        Opcija1Label2        matlab.ui.control.Label
        Opcija1Image1        matlab.ui.control.Image
        Opcija1Label1        matlab.ui.control.Label
        UNESIButton          matlab.ui.control.Button
        Label_10             matlab.ui.control.Label
        Image9               matlab.ui.control.Image
        Image8               matlab.ui.control.Image
        Image7               matlab.ui.control.Image
        Image6               matlab.ui.control.Image
        Image5               matlab.ui.control.Image
        Label_9              matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        Image4               matlab.ui.control.Image
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Tpv                  matlab.ui.control.NumericEditField
        sLabel_3             matlab.ui.control.Label
        Kpv                  matlab.ui.control.NumericEditField
        T2                   matlab.ui.control.NumericEditField
        sLabel_2             matlab.ui.control.Label
        K2                   matlab.ui.control.NumericEditField
        T1                   matlab.ui.control.NumericEditField
        sLabel               matlab.ui.control.Label
        K1                   matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
        UnesitezadaneparametrelanovaregulacijskogkrugaLabel  matlab.ui.control.Label
        Label                matlab.ui.control.Label
        Button               matlab.ui.control.Button
        SINTEZAREGULACIJSKOGSUSTAVASASTATIKIMPROCESOMLabel  matlab.ui.control.Label
        Opcija2Label1        matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        K_R
        T_I
    end
    
    methods (Access = private)
        
        function opcijaPrva(app, stanje)
            if stanje == 1
                vidljivo = 'on';
            else
                vidljivo = 'off';
            end
            % Prikaz teksta itd
            app.Opcija1Label1.Visible = vidljivo;
            app.Opcija1Label2.Visible = vidljivo;
            app.Opcija1Label3.Visible = vidljivo;
            app.Opcija1Label4.Visible = vidljivo;
            app.Opcija1Label5.Visible = vidljivo;
            app.Opcija1Label6.Visible = vidljivo;
            app.Opcija1Label7.Visible = vidljivo;
            app.Opcija1Label8.Visible = vidljivo;
            app.Opcija1Label9.Visible = vidljivo;
            app.Opcija1Label10.Visible = vidljivo;
            app.Opcija1Label11.Visible = vidljivo;
            app.Opcija1Label12.Visible = vidljivo;
            app.Opcija1Label13.Visible = vidljivo;
            app.Opcija1Label14.Visible = vidljivo;
            app.Opcija1Image1.Visible = vidljivo;
            app.Opcija1Image2.Visible = vidljivo;
            app.Opcija1Image3.Visible = vidljivo;
            app.Opcija1Image4.Visible = vidljivo;
            app.sigmaM.Visible = vidljivo;
            app.APROKSIMACIJAButton.Visible = vidljivo;
            app.racunajK.Visible = vidljivo;
            app.phioRt.Visible = vidljivo;
            app.nagibPravca.Visible = vidljivo;
            app.phi.Visible = vidljivo;
            app.omegaPrijeloma.Visible = vidljivo;
            app.omegaC.Visible = vidljivo;
            app.KRt.Visible = vidljivo;
            app.T_I = app.T1.Value;
            
        end

        function opcijaDruga(app, stanje)
            if stanje == 1
                vidljivo = 'on';
            else
                vidljivo = 'off';
            end

            app.Opcija2Label1.Visible = vidljivo;
            app.Opcija2Label2.Visible = vidljivo;
            app.Opcija2Label3.Visible = vidljivo;
            app.Opcija2Label4.Visible = vidljivo;
            app.Opcija2Label5.Visible = vidljivo;
            app.Opcija2Label6.Visible = vidljivo;
            app.Opcija2Image1.Visible = vidljivo;
            app.Opcija2Image2.Visible = vidljivo;
            app.RacunajButton.Visible = vidljivo;
            app.TIsField.Visible = vidljivo;
            app.omegaIS.Visible = vidljivo;
            app.omegaCS.Visible = vidljivo;
            app.omegaC_2.Visible = vidljivo;
            app.KRt_2.Visible = vidljivo;
            app.T_I = app.TIsField.Value;
        end

        function approxPhaseFromBreakPoints(app, nule, polovi, ax)
           
            % Prikazuje aproksimaciju faze iz poznatih lomnih točaka
            w = logspace(-3, 2, 1000);  % frekvencijski raspon
            phase = -90*ones(size(w));
            if any(abs(polovi) < 1e-6)
                phase = phase - 90;
            end

            % Doprinos nula
            for i = 1:length(nule)
                w0 = nule(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = 90;
                    else
                        dp = 45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end
            for i = 1:length(polovi)
                if polovi(i) == 0
                    phase = phase - 90;  % konstantan doprinos za sve frekvencije
                end
            end

            % Doprinos polova
            for i = 1:length(polovi)
                xline(ax, 10,'--');
                w0 = polovi(i);
                if w0 == 0, continue;end
                for j = 1:length(w)
                    if w(j) < w0/10
                        dp = 0;
                    elseif w(j) > 10*w0
                        dp = -90;
                    else
                        dp = -45 * log10(w(j)/(w0/10));
                    end
                    phase(j) = phase(j) + dp;
                end
            end

            % Crtanje
            semilogx(ax, w, phase, 'b-', 'LineWidth', 2);
            title(ax, 'Fazno-frekvecijska karakteristika');
            xlabel(ax, 'Frekvencija [rad/s]');
            ylabel(ax, 'Faza [°]');
            grid(ax, 'on');
            hold(ax, 'on');
            % prikaz polova
                omega_1 = 1/app.T1.Value; % Prvo w_1, pa 0.1w_1, pa 10w_1
                xline(ax, omega_1, '--', 'Color', [0.8500, 0.3250, 0.0980], ...
                'Alpha', 1, 'Label', sprintf('\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_1/10, '--','Color', [1 0 0], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_1*10, '--', 'Color', [1 0 0], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_1'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_2 = 1/app.T2.Value; % Prvo w_2, pa 0.1w_2, pa 10w_2
                xline(ax, omega_2, '--', 'Color', [0.9290, 0.6940, 0.1250], ...
                'Alpha', 1, 'Label', sprintf('\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2/10, '--','Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_2*10, '--', 'Color', [0.75, 0.75, 0], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_2'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_pv = 1/app.Tpv.Value; % Prvo w_pv, pa 0.1w_pv, pa 10w_pv
                xline(ax, omega_pv, '--', 'Color', [0.3010, 0.7450, 0.9330], ...
                'Alpha', 1, 'Label', sprintf('\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv/10, '--','Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_pv*10, '--', 'Color', [0, 0.75, 0.75], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_p_v'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);

                omega_I = 1/app.T_I; % Prvo w_I, pa 0.1w_I, pa 10w_I
                xline(ax, omega_I, '--', 'Color', [0.4940, 0.1840, 0.5560], ...
                'Alpha', 1, 'Label', sprintf('\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_I/10, '--','Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('0.1\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
                xline(ax, omega_I*10, '--', 'Color', [0.25, 0.25, 0.25], ...
                'Alpha', 1, 'Label', sprintf('10\\omega_I'), ...
                    'LabelOrientation', 'horizontal', 'FontSize', 9);
            hold(ax, 'off');

            % ispis vrijednosti u posbnom prozoru
            % Otvori novi uifigure prozor
            f = uifigure('Name', 'Polovi i nule sustava', 'Position', [100 10 350 200]);

            % Prikaži tekst u labeli
            uilabel(f, ...
                'Text', sprintf('Polovi i nule sustava su: [rad/s]'), ...
                'Position', [20 80 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_1 = %.2g, 0.1ω_1 = %.2g, 10ω_1 = %.2g', omega_1,0.1*omega_1,10*omega_1), ...
                'Position', [20 60 300 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_2 = %.2g, 0.1ω_2 = %.2g, 10ω_2 = %.2g', omega_2,0.1*omega_2,10*omega_2), ...
                'Position', [20 40 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_pv = %.2g, 0.1ω_pv = %.2g, 10ω_pv = %.2g', omega_pv,0.1*omega_pv,10*omega_pv), ...
                'Position', [20 20 260 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
            uilabel(f, ...
                'Text', sprintf('ω_I = %.2g, 0.1ω_I = %.2g, 10ω_I = %.2g', omega_I,0.1*omega_I,10*omega_I), ...
                'Position', [20 0 300 140], ...
                'FontSize', 14, ...
                'HorizontalAlignment', 'left');
    end
        function crtajAsimptotskuAFK(app, omega_c, pocetni_nagib, nule, polovi)
    % Postavke
            w = logspace(log10(omega_c)-2, log10(omega_c)+2, 1000);
            mag = zeros(size(w));
            index_wc = find(w >= omega_c, 1);
            mag(index_wc) = 0;

            % Složimo sve lomne točke i pripremimo promjenu nagiba
            nule = nule(:).';   % pretvori u redak
            polovi = polovi(:).';  % isto
            lomne = [nule, polovi];
            promjene = [20*ones(1, numel(nule)), -20*ones(1, numel(polovi))];  % +20 za nule, -20 za polove

            % Sortiraj po frekvenciji
            [lomne_sort, idx_sort] = sort(lomne);
            promjene_sort = promjene(idx_sort);

            % Inicijalni nagib
            nagib = pocetni_nagib * 20;

            % Desno od ωc
            for i = index_wc+1:length(w)
                dw = log10(w(i)) - log10(w(i-1));
                mag(i) = mag(i-1) + nagib * dw;

                % Provjeri ima li lomna točka između w(i-1) i w(i)
                for j = 1:length(lomne_sort)
                    if w(i-1) < lomne_sort(j) && lomne_sort(j) <= w(i)
                        nagib = nagib + promjene_sort(j);
                    end
                end
            end

            % Lijevo od ωc
            nagib = pocetni_nagib * 20;
            for i = index_wc-1:-1:1
                dw = log10(w(i+1)) - log10(w(i));
                mag(i) = mag(i+1) - nagib * dw;

                for j = 1:length(lomne_sort)
                    if w(i) <= lomne_sort(j) && lomne_sort(j) < w(i+1)
                        nagib = nagib - promjene_sort(j);
                    end
                end
            end

            % Crtanje
            cla(app.UIAxes);
            semilogx(app.UIAxes, w, mag, 'r-', 'LineWidth', 2);
            hold(app.UIAxes, 'on');

            % Označi ωc
            y_wc = mag(index_wc);
            plot(app.UIAxes, omega_c, y_wc, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'k');
            text(app.UIAxes, omega_c, y_wc+5, '\omega_c', 'HorizontalAlignment', 'center');

            % Označi lomne frekvencije
            for w_b = lomne
                xline(app.UIAxes, w_b, '--k', 'Alpha', 0.4);
            end

            % Osa i naslovi
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Amplitudno-frekvencijska karakteristika');
            xlabel(app.UIAxes, 'Frekvencija [rad/s]');
            ylabel(app.UIAxes, 'Magnituda [dB]');
            % Od točke pri w = 0.001
            w_ref = omega_c/100;
            idx = find(w >= w_ref, 1);
            A_ref = mag(idx);

            % Izračun nagiba lokalno (na 2 točke)
            slope = (mag(idx+1) - mag(idx)) / (log10(w(idx+1)) - log10(w(idx)));  % u dB/dec
            b = A_ref - slope * log10(w_ref);

            % Produži pravac po cijelom w
            mag_pocetni = slope * log10(w) + b;
            semilogx(app.UIAxes, w, mag_pocetni, 'b-.', 'LineWidth', 1.5);

            % Presjek s 0 dB osi
            if slope ~= 0
                w0 = 10^(-b / slope);
                plot(app.UIAxes, w0, 0, 'ko', 'MarkerSize', 6, 'MarkerFaceColor', 'g');
                text(app.UIAxes, w0, 5, ['K_{oR} ≈ ', num2str(w0, '%.2f')], ...
                    'Color', 'g', 'FontSize', 10, 'HorizontalAlignment', 'center');
            else
                % Ako je nagib 0, pravac nikad ne presiječe 0 dB
                text(app.UIAxes, w_ref, A_ref + 5, 'Ravan pravac (nagib = 0)', ...
                    'Color', 'b', 'FontSize', 10, 'HorizontalAlignment', 'left');
            end
            app.omegaC.Value = w0;
            app.omegaC_2.Value = w0;
            K_Rs = (app.omegaC.Value*app.T_I)/(app.K1.Value*app.K2.Value*app.Kpv.Value);
            app.KRt.Value = K_Rs;
            app.KRt_2.Value = K_Rs;
            app.K_R = K_Rs;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            MainMenu();
            delete(app);
        end

        % Button pushed function: UNESIButton
        function UNESIButtonPushed(app, event)
            omjer = app.T1.Value/app.T2.Value;
            if omjer < 8
                opcijaPrva(app, 1);
                opcijaDruga(app, 0);
            elseif omjer >= 8 && omjer < 16
                opcijaPrva(app, 0);
                opcijaDruga(app, 1);
            elseif omjer >= 16 && omjer < 100
                opcijaPrva(app, 0);
                opcijaDruga(app, 1);
            else
                opcijaPrva(app, 0);
                opcijaDruga(app, 1);
            end
        end

        % Button pushed function: APROKSIMACIJAButton
        function APROKSIMACIJAButtonPushed(app, event)
            omega_2 = 1/app.T2.Value;
            omega_pv = 1/app.Tpv.Value;
            nule = [];
            polovi = [omega_2 omega_pv];
            app.approxPhaseFromBreakPoints(nule, polovi, app.UIAxes_2);
            sigma_m = app.sigmaM.Value;
            gama = 60 - sigma_m;
            fi_omegaC = gama - 180;
            app.phioRt.Value = fi_omegaC;
        end

        % Button pushed function: racunajK
        function racunajKButtonPushed(app, event)
            omega_2 = 1/app.T2.Value;
            omega_pv = 1/app.Tpv.Value;
            nule = [];
            polovi = [omega_2 omega_pv];
            nagib = app.nagibPravca.Value;
            fi = app.phi.Value;
            omega_P = app.omegaPrijeloma.Value;
            fiort = app.phioRt.Value;

            % Izračun KoRt
            if(nagib==-1)
                omega_c = omega_P * 10^((-fiort+fi)/45);
                app.omegaC.Value = omega_c;
            end
            if(nagib==-2)
                omega_c = omega_P * 10^((-fiort+fi)/90);
                app.omegaC.Value = omega_c;
            end

            % Izračun KRt
            app.T_I = app.T1.Value;
            K_Rt = (app.omegaC.Value*app.T_I)/(app.K1.Value*app.K2.Value*app.Kpv.Value);
            app.KRt.Value = K_Rt;
            app.crtajAsimptotskuAFK(omega_c, nagib, nule, polovi);
            app.K_R = K_Rt;
        end

        % Button pushed function: RacunajButton
        function RacunajButtonPushed(app, event)
            omjer = app.T1.Value/app.T2.Value;
            
            if omjer >= 8 && omjer < 16
                a = 2;
            elseif omjer >= 16 && omjer < 100
                a_kvadrat = sqrt(omjer);
                a = sqrt(a_kvadrat);
            elseif omjer >= 100
                a_kvadrat = 10;
                a = sqrt(a_kvadrat);
            end
            
            omega_1 = 1/app.T1.Value;
            omega_2 = 1/app.T2.Value;
            omega_pv = 1/app.Tpv.Value;
            if omega_pv > omega_2
                omega_cs = omega_2/a;
                app.omegaCS.Value = omega_cs;
            else
                omega_cs = omega_pv/a;
                app.omegaCS.Value = omega_cs;
            end
            omega_I = omega_cs/a;
            app.omegaIS.Value = omega_I;
            TIs = 1/omega_I;
            app.T_I =  TIs;
            app.TIsField.Value = TIs;
            pocetni_nagib = -1;
            nule = [omega_I];
            polovi = [omega_1; omega_2; omega_pv];
            app.crtajAsimptotskuAFK(omega_cs, pocetni_nagib, nule, polovi);
            app.approxPhaseFromBreakPoints(nule, polovi, app.UIAxes_2);

        end

        % Button pushed function: STEPButton
        function STEPButtonPushed(app, event)
            % Napravi novi prozor (skočni)
            f = uifigure('Name', 'Odziv na STEP funkciju', 'Position', [100 100 500 400]);
    
            % Dodaj axes za odziv
            ax = uiaxes(f, 'Position', [20 20 450 350]);
    
            num1 = [app.K1.Value];
            den1 = [app.T1.Value 1];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            num_r = [app.T_I*app.K_R 1*app.K_R];
            den_r = [app.T_I 0];
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
            sys = feedback(G_d,G_pv);
            % Nacrtaj odaziv na step funkciju
            [t, y] = step(sys);
            plot(ax, y, t, 'b-', 'LineWidth', 1);
            xlabel(ax, 'Vrijeme [s]');
            ylabel(ax, 'Vrijednost odziva');
            title(ax, 'Odaziv sustava na step funkciju');
            grid(ax, 'on');
        end

        % Button pushed function: BODEButton
        function BODEButtonPushed(app, event)

            num1 = [app.K1.Value];
            den1 = [app.T1.Value 1];
            G1 = tf(num1, den1);
            num2 = [app.K2.Value];
            den2 = [app.T2.Value 1];
            G2 = tf(num2, den2);
            num_pv = [app.Kpv.Value];
            den_pv = [app.Tpv.Value 1];
            G_pv = tf(num_pv, den_pv);
            num_r = [app.T_I*app.K_R 1*app.K_R];
            den_r = [app.T_I 0];
            G_R = tf(num_r, den_r);
            G_d = G1*G2*G_R;
       
            G_oR = G_d*G_pv;
            margin(G_oR);
            title('Bodeov dijagram');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Opcija2Label1 = uilabel(app.UIFigure);
            app.Opcija2Label1.Visible = 'off';
            app.Opcija2Label1.Text = {'Integracijska vremenska konstanta PI regulatora odabire se '; 'tako da vrijedi: T1 > TIs > max(T2, Tpv).'; 'Parametrima i prijenosnim funkcijama regulatora i otvorenog'; 'kruga s regulatorom dodaje se indeks s.'; 'Simetrični optimum – postizanje simetrične frekvencijske'; 'karakteristike oko presječne frekvencije ωcs.'; 'Nagib amplitudno-frekvencijske karakteristike oko ωcs mora'; 'iznositi -1, tj. -20 dB/dek.'; 'Širina nagiba -1 oko ωcs (parametar a) određuje iznos '; 'presječne frekvencije ωcs i integracijske vremenske'; 'konstante regulatora TIs.'};
            app.SINTEZAREGULACIJSKOGSUSTAVASASTATIKIMPROCESOMLabel = uilabel(app.UIFigure);
            app.SINTEZAREGULACIJSKOGSUSTAVASASTATIKIMPROCESOMLabel.Text = 'SINTEZA REGULACIJSKOG SUSTAVA SA STATIČKIM PROCESOM';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Za pravilno korištenje programa pratite upute '; 'koje se nalaze u glavnom izborniku'; ''};
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel = uilabel(app.UIFigure);
            app.UnesitezadaneparametrelanovaregulacijskogkrugaLabel.Text = '1. Unesite zadane parametre članova regulacijskog kruga:';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Prijenosne funkcije statickog.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '____________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '____________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '____________';
            app.K1 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '1 +            s';
            app.T1 = uieditfield(app.UIFigure, 'numeric');
            app.K2 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '1 +            s';
            app.T2 = uieditfield(app.UIFigure, 'numeric');
            app.Kpv = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_3 = uilabel(app.UIFigure);
            app.sLabel_3.Text = '1 +            s';
            app.Tpv = uieditfield(app.UIFigure, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Prijenosne funkcije statickog procesa.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'PI regulator.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'prijenosna procesa sa PI.png');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Budući da ovaj proces nema astatizma, nužno je izabrati regulator s integracijskim '; 'ponašanjem da se dobije red astatizma otvorenog kruga s regulatorom r = 1'; 'Ne izabire se samo I regulator, nego se dodaje i P komponenta radi ubrzanja odziva, '; 'smanjenja kinetičke pogreške i bolje kompenzacije poremećaja -> PI regulator'};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'Uz postignut red astatizma r = 1, zadovoljeni su zahtjevi za potpunom kompenzacijom '; 'poremećajne veličine (δfs = 0), te statičkom pogreškom u odnosu na referentnu '; 'veličinu jednakoj nuli (δus = 0).'};
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'staticki23', '1.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'staticki23', '2.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'staticki23', '3.png');
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'staticki23', '4.png');
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'staticki23', '5.png');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Nakon unosa parametara članova regulacijskog kruga, prisnite na tipku ispod';
            app.UNESIButton = uibutton(app.UIFigure, 'push');
            app.UNESIButton.ButtonPushedFcn = createCallbackFcn(app, @UNESIButtonPushed, true);
            app.UNESIButton.Text = 'UNESI';
            app.Opcija1Label1 = uilabel(app.UIFigure);
            app.Opcija1Label1.Visible = 'off';
            app.Opcija1Label1.Text = {'Prijenosna jednadžba regulatora nakon odabira tehničkog'; ' optimuma sada izgleda ovako: '};
            app.Opcija1Image1 = uiimage(app.UIFigure);
            app.Opcija1Image1.Visible = 'off';
            app.Opcija1Image1.ImageSource = fullfile(pathToMLAPP, 'tehopt1red.png');
            app.Opcija1Label2 = uilabel(app.UIFigure);
            app.Opcija1Label2.Visible = 'off';
            app.Opcija1Label2.Text = {'Budući da je jedina nepoznanica pojačanje K_oRt, te ona'; 'mora biti pozitivna, ona ne utječe na FFK i može se nacrati'; 'njena aproksimacija pravcima'};
            app.Opcija1Label3 = uilabel(app.UIFigure);
            app.Opcija1Label3.Visible = 'off';
            app.Opcija1Label3.Text = {'Iz zadanog nadvišenja odziva σ_m računa se potrebno '; 'fazno osiguranje γ korištenjem empirijske relacije:'};
            app.sigmaM = uieditfield(app.UIFigure, 'numeric');
            app.sigmaM.Visible = 'off';
            app.APROKSIMACIJAButton = uibutton(app.UIFigure, 'push');
            app.APROKSIMACIJAButton.ButtonPushedFcn = createCallbackFcn(app, @APROKSIMACIJAButtonPushed, true);
            app.APROKSIMACIJAButton.Visible = 'off';
            app.APROKSIMACIJAButton.Text = 'APROKSIMACIJA';
            app.Opcija1Image4 = uiimage(app.UIFigure);
            app.Opcija1Image4.Visible = 'off';
            app.Opcija1Image4.ImageSource = fullfile(pathToMLAPP, 'prviM.png');
            app.Opcija1Image2 = uiimage(app.UIFigure);
            app.Opcija1Image2.Visible = 'off';
            app.Opcija1Image2.ImageSource = fullfile(pathToMLAPP, 'empirijska relacija.png');
            app.Opcija1Label6 = uilabel(app.UIFigure);
            app.Opcija1Label6.Visible = 'off';
            app.Opcija1Label6.Text = {'Time je poznata i presječna frekvencija na kojoj se očitava'; 'fazno osiguranje:'};
            app.Opcija1Image3 = uiimage(app.UIFigure);
            app.Opcija1Image3.Visible = 'off';
            app.Opcija1Image3.ImageSource = fullfile(pathToMLAPP, 'fazno osiguranje.png');
            app.Opcija1Label7 = uilabel(app.UIFigure);
            app.Opcija1Label7.Visible = 'off';
            app.Opcija1Label7.Text = 'Vrijeme prvog maksimuma procjenjuje se iz presječne frek. :';
            app.Opcija1Label5 = uilabel(app.UIFigure);
            app.Opcija1Label5.Visible = 'off';
            app.Opcija1Label5.Text = 'σ_m =';
            app.Opcija1Label4 = uilabel(app.UIFigure);
            app.Opcija1Label4.Visible = 'off';
            app.Opcija1Label4.Text = 'Unesi:';
            app.phioRt = uieditfield(app.UIFigure, 'numeric');
            app.phioRt.Visible = 'off';
            app.Opcija1Label9 = uilabel(app.UIFigure);
            app.Opcija1Label9.Visible = 'off';
            app.Opcija1Label9.Text = {'Očitaj i upiši stanje na posljednjoj promjeni nagiba '; 'pravca prije kuta presječne frekvencije označenog lijevo:'};
            app.nagibPravca = uieditfield(app.UIFigure, 'numeric');
            app.nagibPravca.Visible = 'off';
            app.phi = uieditfield(app.UIFigure, 'numeric');
            app.phi.Visible = 'off';
            app.omegaPrijeloma = uieditfield(app.UIFigure, 'numeric');
            app.omegaPrijeloma.Visible = 'off';
            app.omegaC = uieditfield(app.UIFigure, 'numeric');
            app.omegaC.Visible = 'off';
            app.KRt = uieditfield(app.UIFigure, 'numeric');
            app.KRt.Visible = 'off';
            app.racunajK = uibutton(app.UIFigure, 'push');
            app.racunajK.ButtonPushedFcn = createCallbackFcn(app, @racunajKButtonPushed, true);
            app.racunajK.Visible = 'off';
            app.racunajK.Text = 'Izračunaj pojačanje';
            app.Opcija1Label10 = uilabel(app.UIFigure);
            app.Opcija1Label10.Visible = 'off';
            app.Opcija1Label10.Text = 'nagib pravca =';
            app.Opcija1Label8 = uilabel(app.UIFigure);
            app.Opcija1Label8.Visible = 'off';
            app.Opcija1Label8.Text = 'φ_oRt (ω_c) =';
            app.Opcija1Label11 = uilabel(app.UIFigure);
            app.Opcija1Label11.Visible = 'off';
            app.Opcija1Label11.Text = 'φ_oRt (ω) =';
            app.Opcija1Label12 = uilabel(app.UIFigure);
            app.Opcija1Label12.Visible = 'off';
            app.Opcija1Label12.Text = 'lomna ω  =';
            app.Opcija1Label13 = uilabel(app.UIFigure);
            app.Opcija1Label13.Visible = 'off';
            app.Opcija1Label13.Text = 'K_oRt = ω_c =';
            app.Opcija1Label14 = uilabel(app.UIFigure);
            app.Opcija1Label14.Visible = 'off';
            app.Opcija1Label14.Text = 'K_Rt =';
            app.Opcija2Image1 = uiimage(app.UIFigure);
            app.Opcija2Image1.Visible = 'off';
            app.Opcija2Image1.ImageSource = fullfile(pathToMLAPP, 'simOpt1r2.png');
            app.Opcija2Image2 = uiimage(app.UIFigure);
            app.Opcija2Image2.Visible = 'off';
            app.Opcija2Image2.ImageSource = fullfile(pathToMLAPP, 'simOpt1r3.png');
            app.Opcija2Label2 = uilabel(app.UIFigure);
            app.Opcija2Label2.Visible = 'off';
            app.Opcija2Label2.Text = 'ωcs =';
            app.omegaCS = uieditfield(app.UIFigure, 'numeric');
            app.omegaCS.Visible = 'off';
            app.Opcija2Label3 = uilabel(app.UIFigure);
            app.Opcija2Label3.Visible = 'off';
            app.Opcija2Label3.Text = 'ωIs =';
            app.omegaIS = uieditfield(app.UIFigure, 'numeric');
            app.omegaIS.Visible = 'off';
            app.Opcija2Label4 = uilabel(app.UIFigure);
            app.Opcija2Label4.Visible = 'off';
            app.Opcija2Label4.Text = 'TIs =';
            app.TIsField = uieditfield(app.UIFigure, 'numeric');
            app.TIsField.Visible = 'off';
            app.RacunajButton = uibutton(app.UIFigure, 'push');
            app.RacunajButton.ButtonPushedFcn = createCallbackFcn(app, @RacunajButtonPushed, true);
            app.RacunajButton.Visible = 'off';
            app.RacunajButton.Text = 'IZRAČUNAJ';
            app.Opcija2Label5 = uilabel(app.UIFigure);
            app.Opcija2Label5.Visible = 'off';
            app.Opcija2Label5.Text = 'K_oRs =';
            app.Opcija2Label6 = uilabel(app.UIFigure);
            app.Opcija2Label6.Visible = 'off';
            app.Opcija2Label6.Text = 'K_Rs =';
            app.STEPButton = uibutton(app.UIFigure, 'push');
            app.STEPButton.ButtonPushedFcn = createCallbackFcn(app, @STEPButtonPushed, true);
            app.STEPButton.Text = 'STEP';
            app.BODEButton = uibutton(app.UIFigure, 'push');
            app.BODEButton.ButtonPushedFcn = createCallbackFcn(app, @BODEButtonPushed, true);
            app.BODEButton.Text = 'BODE';
            app.omegaC_2 = uieditfield(app.UIFigure, 'numeric');
            app.omegaC_2.Visible = 'off';
            app.KRt_2 = uieditfield(app.UIFigure, 'numeric');
            app.KRt_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatickiProces

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end